classdef ExampleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        ImageButton              matlab.ui.container.toolbar.PushTool
        SettingButton            matlab.ui.container.toolbar.PushTool
        GridLayout               matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        BranchDiameterDataPanel  matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        Diameter3Label           matlab.ui.control.Label
        Diameter2Label           matlab.ui.control.Label
        Diameter1Label           matlab.ui.control.Label
        Branch3Label             matlab.ui.control.Label
        Branch2Label             matlab.ui.control.Label
        Branch1Label             matlab.ui.control.Label
        ShowButton               matlab.ui.control.Button
        DrawButton               matlab.ui.control.Button
        LinkDataPanel            matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        LengthLabel              matlab.ui.control.Label
        LinksLength              matlab.ui.control.Label
        LinksTortuosity          matlab.ui.control.Label
        TortuosityLabel          matlab.ui.control.Label
        NodeDataPanel            matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        XLabel                   matlab.ui.control.Label
        NodeZ                    matlab.ui.control.Label
        NodeY                    matlab.ui.control.Label
        NodeX                    matlab.ui.control.Label
        ZLabel                   matlab.ui.control.Label
        YLabel                   matlab.ui.control.Label
        LinksDropDown            matlab.ui.control.DropDown
        LinksDropDownLabel       matlab.ui.control.Label
        NodesDropDown            matlab.ui.control.DropDown
        NodesDropDownLabel       matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        SurfaceTab               matlab.ui.container.Tab
        SurfaceAxes              matlab.ui.control.UIAxes
        SkelTab                  matlab.ui.container.Tab
        SkelAxes                 matlab.ui.control.UIAxes
        SurfSkelTab              matlab.ui.container.Tab
        TotalAxes                matlab.ui.control.UIAxes
        SelectedLinkAxes         matlab.ui.control.UIAxes
        SelectedNodeAxes         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        DialogApp                   % Dialog box app
        %% PREREQUISITE INPUT
        testdata = 0;
        % Length ratio between xy pixel and z pixel  
        xy_z_ratio=4;
        % To form realistic structure, image stack will be elongated to match the
        % x-y-z length ratio as 1:1:1.
        % input =(z pixel distance) / (xy pixel distance)
        % WARNING: The input must be in integer form!! 
        
        % Prune the branches of raw skeleton 
        pruning_length=50;
        % branches of skeletons which contain less than x number of pixels will be
        % pruned 
        
        % Condition for searching bifurcation node
        bifur_link_condition=20;
        % Only nodes with all links longer than x pixels will be chosen as
        % bifurcation node
        
        % Number of pixels for calculating gradient vector
        num_pixel_grad_vec=5;
        % Gradient vector of each bifurcated link will be calculated based on
        % inputted number. The number counting starts from bifurcation node
        
        % Condition for searching investigated link 
        link_search_condition=20;
        % Only links longer than x pixels will be chosen and investigated 
        
        %VolumeViewer
        
        stack; %이미지 3D
        
        skel; %기본 skel
        
        w;
        
        h;
        
        l;
        
        skel2; %정리된 skel
        
        node2; %정리된 노드
        
        link2; %정리된 링크
        
        bifur_node; %걸러진 노드 인덱스
        
        link_info; %링크 정보
        
        node_info_3d;
        
        node_info_final; %걸러진 노드
        
        specific_link; %걸러진 링크
        
        isosurf; %iossuface
        
        isocaps; 
        
        %isonormals;
        
        marked_node;
        
        marked_link_start;
        
        marked_link_end;
        
        selected_links_points;
        
        selected_links_plot;
        
    end
    
    methods (Access = private)
        
         function DrawInAxes(app, Axes, IsSurf, IsSkel)
             if IsSurf == 1
                 col=[.9 .9 .9];
             else
                 col='none';
             end
             
             if IsSkel == 1
                 SkelCol='r';
             else
                 SkelCol='none';
             end
             
             cla(Axes,'reset');
             hold(Axes,'on');
             hiso = patch(Axes,app.isosurf,'FaceColor',col,'EdgeColor','none');
             patch(Axes,app.isocaps,'FaceColor',col,'EdgeColor','none');
             lighting(Axes,'phong');
             isonormals(app.stack,hiso);
             alpha(Axes,0.5);
             set(Axes,'DataAspectRatio',[1 1 1]);
             camlight(Axes,'right');
             hold(Axes,'on');
             [x,y,z]=ind2sub([app.w,app.l,app.h],find(app.skel2(:)));
             plot3(Axes,y,x,z,'square','Markersize',1,'MarkerFaceColor',SkelCol,'Color',SkelCol);            
             set(Axes,'Color',[0.95,0.95,0.95]);
             view(Axes,[1229 512 46]);
             set(Axes,'XColor', 'none','YColor','none','ZColor','none');
         end
         
         function DrawCrossSection(app, NodeNumber)
             %clf(app.CrossAxes_1,'reset')
             x_1 = app.node_info_final(NodeNumber).branch1_diameter_blob(:,1);
             y_1 = app.node_info_final(NodeNumber).branch1_diameter_blob(:,2);
             z_1 = app.node_info_final(NodeNumber).branch1_diameter_blob(:,3);
             x_2 = app.node_info_final(NodeNumber).branch1_diameter_blob(:,1);
             y_2 = app.node_info_final(NodeNumber).branch1_diameter_blob(:,2);
             z_2 = app.node_info_final(NodeNumber).branch1_diameter_blob(:,3);
             x_3 = app.node_info_final(NodeNumber).branch1_diameter_blob(:,1);
             y_3 = app.node_info_final(NodeNumber).branch1_diameter_blob(:,2);
             z_3 = app.node_info_final(NodeNumber).branch1_diameter_blob(:,3);
             
             for i=1:size(x_1)
                temp(x_1(i),y_1(i),z_1(i)) = 1;
             end
            
             col=[1 1 1];
             hiso = patch(app.CrossAxes_1,isosurface(temp,0),'FaceColor',col,'EdgeColor','none');
             %lighting(app.CrossAxes_1,'phong');
             isonormals(temp,hiso);
             %alpha(app.CrossAxes_1,0.5);
             set(app.CrossAxes_1,'DataAspectRatio',[1 1 1]);
             app.CrossAxes_1.YLim = [min(x_1) max(x_1)];
             app.CrossAxes_1.XLim = [min(y_1) max(y_1)];
             app.CrossAxes_1.ZLim = [min(z_1) max(z_1)];
%             camlight(app.CrossAxes_1,'right');
             
%              xv = app.node_info_final(NodeNumber).branch1_diameter_grad(2);
%              yv = app.node_info_final(NodeNumber).branch1_diameter_grad(1);
%              zv = app.node_info_final(NodeNumber).branch1_diameter_grad(3);
%             
%              view(app.CrossAxes_1,[xv,yv,zv]);
             disp('end')
             disp(NodeNumber)
            
                         
         end
     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: ImageButton
        function ImageButtonClicked(app, event)
            %global stack;
            %disp(app.testdata+1);
            %[path] = uigetdir;
            %stack=BinImageBuild(path, app.xy_z_ratio);
            app.ImageButton.Enable = 'off';
            app.DialogApp = SettingPanel(app);
        end

        % Clicked callback: SettingButton
        function SettingButtonClicked(app, event)
            % Disable Plot Options button while dialog is open
            app.SettingButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = SettingPanel(app, 35, 'Parula');
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            p = msgbox('Processing...');
            disp(app.xy_z_ratio);
            disp(app.pruning_length);
            disp(app.bifur_link_condition);
            disp(app.num_pixel_grad_vec);
            disp(app.link_search_condition);
            
            %3d skeletonization
            app.skel = bwskel(app.stack); %내장함수 스켈
            app.w=size(app.skel,1); %이후의 계산을 위해 w,l,h를 별도로 저장할 것 !!!
            app.l=size(app.skel,2); %
            app.h=size(app.skel,3); %

            [app.skel2, app.node2, app.link2]=SkeletonPruning(app.skel, app.pruning_length);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% BIFURCATION POINT SEARCH 
            %입력 - 정리한 노드, 정리한 링크, 찾는 노드의 조건(노드에 연결된 링크의 최소 길이)
            %출력 - 조건 충족 노드 인덱스
            app.bifur_node=BifurNodeSearch(app.node2,app.link2, app.bifur_link_condition);
            disp('bifur_node end')
            %% 3D NODE INFORMATION STRUCTURE CREATION 
            %입력 - 인덱스, x, y, z
            app.node_info_3d=struct('bifurcation_index',cell(length(app.bifur_node),1),'comx',cell(length(app.bifur_node),1),'comy',cell(length(app.bifur_node),1), 'comz',cell(length(app.bifur_node),1),...
            'branch1_points',cell(length(app.bifur_node),1),'branch1_gradient',cell(length(app.bifur_node),1),'branch1_diameter',cell(length(app.bifur_node),1),'branch1_gradient_rmse',cell(length(app.bifur_node),1),'branch1_diameter_merror',cell(length(app.bifur_node),1),'branch1_diameter_point',cell(length(app.bifur_node),1),'branch1_diameter_grad',cell(length(app.bifur_node),1),'branch1_diameter_blob',cell(length(app.bifur_node),1),...
            'branch2_points', cell(length(app.bifur_node),1),'branch2_gradient',cell(length(app.bifur_node),1),'branch2_diameter',cell(length(app.bifur_node),1), 'branch2_gradient_rmse',cell(length(app.bifur_node),1),'branch2_diameter_merror',cell(length(app.bifur_node),1),'branch2_diameter_point',cell(length(app.bifur_node),1),'branch2_diameter_grad',cell(length(app.bifur_node),1),'branch2_diameter_blob',cell(length(app.bifur_node),1),...
            'branch3_points', cell(length(app.bifur_node),1),'branch3_gradient',cell(length(app.bifur_node),1),'branch3_diameter',cell(length(app.bifur_node),1), 'branch3_gradient_rmse',cell(length(app.bifur_node),1),'branch3_diameter_merror',cell(length(app.bifur_node),1), 'branch3_diameter_point',cell(length(app.bifur_node),1),'branch3_diameter_grad',cell(length(app.bifur_node),1),'branch3_diameter_blob',cell(length(app.bifur_node),1));
            disp('node_info_3d end')
            %% BIFURCATION ANGLE CALCULATION 
            %Gradient 채워줌
            app.node_info_3d=BifurAngleCal(app.node_info_3d,app.w, app.l, app.h, app.node2,app.link2, app.bifur_node, app.num_pixel_grad_vec);
            
            %% BIFURCATION DIAMETER CALCULATION
            % 직경 채워줌
            app.node_info_final=DiameterCalculation(app.stack, app.node_info_3d, app.w, app.l, app.h, app.node2, app.link2, app.bifur_node);
            
            %% SPECIFIC LINK SEARCH 
            %입력 - 정리된 링크, 길이
            %출력 - 조건 충족 링크 인덱스
            app.specific_link=LinkSearch(app.link2, app.link_search_condition);
            
            %% 3D LINK INFORMATION STRUCTURE CREATION 
            %struct 만들기
            %인덱스, 시작점, 끝점, 구성 점 인덱스, 길이, 구불구불한 정도
            app.link_info=struct('link_index',cell(length(app.specific_link),1),'start',cell(length(app.specific_link),1),'end',cell(length(app.specific_link),1), 'points',cell(length(app.specific_link),1),...
            'length',cell(length(app.specific_link),1),'tortuosity',cell(length(app.specific_link),1));
            
            %% LINK INFORMATION INSERTION
            %채우기
            app.link_info=LinkCalculation(app.link_info, app.w,app.l,app.h, app.node2,app.link2, app.specific_link);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            disp('end')
            
            %Node_DropDown_Items = app.Struct2StrList([app.bifur_node]);
            
            app.NodesDropDown.Items = string(app.bifur_node);
            app.LinksDropDown.Items = string(app.specific_link);
            delete(p);
            msgbox('Complete!')
            
        end

        % Value changed function: NodesDropDown
        function NodesDropDownValueChanged(app, event)
            try
                delete(app.marked_node);
            catch
                
            end
            value = app.NodesDropDown.Value;
            selected = find([app.node_info_final.bifurcation_index] == str2num(value));
            X = app.node_info_final(selected).comx;
            Y = app.node_info_final(selected).comy;
            Z = app.node_info_final(selected).comz;
            app.NodeX.Text = string(X);
            app.NodeY.Text = string(Y);
            app.NodeZ.Text = string(Z);
            
            Diameter1 = app.node_info_final(selected).branch1_diameter;
            Diameter2 = app.node_info_final(selected).branch2_diameter;
            Diameter3 = app.node_info_final(selected).branch3_diameter;
            app.Diameter1Label.Text = string(Diameter1);
            app.Diameter2Label.Text = string(Diameter2);
            app.Diameter3Label.Text = string(Diameter3);
            
            XRange = [X-50 X+50];
            YRange = [Y-50 Y+50];
            ZRange = [Z-10 Z+10];
            
            %DrawCrossSection(app, selected)
            xv = app.node_info_final(selected).branch1_diameter_point(1);
            yv = app.node_info_final(selected).branch1_diameter_point(2);
            zv = app.node_info_final(selected).branch1_diameter_point(3);
            
            
%             app.CrossAxes_1.YLim = [X-10 X+10];
%             app.CrossAxes_1.XLim = [Y-10 Y+10];
%             app.CrossAxes_1.ZLim = [Z-10 Z+10];
%             view(app.CrossAxes_1,[xv,yv,zv]);
            
            app.marked_node = plot3(app.SelectedNodeAxes,Y,X,Z,'square','Markersize',10,'MarkerFaceColor','r','Color','r');
            app.SelectedNodeAxes.XLim = YRange;
            app.SelectedNodeAxes.YLim = XRange;
            app.SelectedNodeAxes.ZLim = ZRange;
        end

        % Value changed function: LinksDropDown
        function LinksDropDownValueChanged(app, event)
            try
                delete(app.marked_link_start);
                delete(app.marked_link_end);
                delete(app.selected_links_plot);
            catch
                
            end
            value = app.LinksDropDown.Value;
            Selected_Link = find([app.link_info.link_index] == str2num(value));
            app.LinksLength.Text = string(app.link_info(Selected_Link).length);
            app.LinksTortuosity.Text = string(app.link_info(Selected_Link).tortuosity);
            X_1 = app.link_info(Selected_Link).start(1);
            X_2 = app.link_info(Selected_Link).end(1);
            Y_1 = app.link_info(Selected_Link).start(2);
            Y_2 = app.link_info(Selected_Link).end(2);
            Z_1 = app.link_info(Selected_Link).start(3);
            Z_2 = app.link_info(Selected_Link).end(3);
            X_mid = (X_1 + X_2)/2;
            Y_mid = (Y_1 + Y_2)/2;
            Z_mid = (Z_1 + Z_2)/2;
            XRange = [X_mid-app.link_info(Selected_Link).length X_mid+app.link_info(Selected_Link).length];
            YRange = [Y_mid-app.link_info(Selected_Link).length Y_mid+app.link_info(Selected_Link).length];
            ZRange = [Z_mid-10 Z_mid+10];
            
            app.marked_link_start = plot3(app.SelectedLinkAxes,Y_1,X_1,Z_1,'square','Markersize',10,'MarkerFaceColor','r','Color','r');
            app.marked_link_end = plot3(app.SelectedLinkAxes,Y_2,X_2,Z_2,'square','Markersize',10,'MarkerFaceColor','r','Color','r');
            
            app.selected_links_points = [app.link_info(Selected_Link).points(:,1),app.link_info(Selected_Link).points(:,2),app.link_info(Selected_Link).points(:,3)];
            app.selected_links_plot = plot3(app.SelectedLinkAxes,app.selected_links_points(:,2),app.selected_links_points(:,1),app.selected_links_points(:,3),'square','Markersize',5,'MarkerFaceColor','b','Color','b');
            
            app.SelectedLinkAxes.XLim = YRange;
            app.SelectedLinkAxes.YLim = XRange;
            app.SelectedLinkAxes.ZLim = ZRange;
            
            
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            p = msgbox('Processing...');
            DrawInAxes(app,app.SurfaceAxes,1,0)
            DrawInAxes(app,app.SkelAxes,0,1)
            DrawInAxes(app,app.TotalAxes,1,1)
            DrawInAxes(app,app.SelectedNodeAxes,1,1)
            DrawInAxes(app,app.SelectedLinkAxes,1,1)
            %DrawInAxes(app,app.CrossAxes_1, 1, 0)
            
            disp('End')
            
            delete(p);
            msgbox('Complete!')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ImageButton = uipushtool(app.Toolbar);
            app.ImageButton.ClickedCallback = createCallbackFcn(app, @ImageButtonClicked, true);
            app.SettingButton = uipushtool(app.Toolbar);
            app.SettingButton.ClickedCallback = createCallbackFcn(app, @SettingButtonClicked, true);
            app.SettingButton.Icon = 'settings.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectedNodeAxes = uiaxes(app.GridLayout);
            xlabel(app.SelectedNodeAxes, 'X')
            ylabel(app.SelectedNodeAxes, 'Y')
            zlabel(app.SelectedNodeAxes, 'Z')
            app.SelectedLinkAxes = uiaxes(app.GridLayout);
            xlabel(app.SelectedLinkAxes, 'X')
            ylabel(app.SelectedLinkAxes, 'Y')
            zlabel(app.SelectedLinkAxes, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SurfaceTab = uitab(app.TabGroup);
            app.SurfaceTab.Title = 'Surface';
            app.SurfaceAxes = uiaxes(app.SurfaceTab);
            xlabel(app.SurfaceAxes, 'X')
            ylabel(app.SurfaceAxes, 'Y')
            zlabel(app.SurfaceAxes, 'Z')
            app.SkelTab = uitab(app.TabGroup);
            app.SkelTab.Title = 'Skel';
            app.SkelAxes = uiaxes(app.SkelTab);
            xlabel(app.SkelAxes, 'X')
            ylabel(app.SkelAxes, 'Y')
            zlabel(app.SkelAxes, 'Z')
            app.SurfSkelTab = uitab(app.TabGroup);
            app.SurfSkelTab.Title = 'Surf+Skel';
            app.TotalAxes = uiaxes(app.SurfSkelTab);
            xlabel(app.TotalAxes, 'X')
            ylabel(app.TotalAxes, 'Y')
            zlabel(app.TotalAxes, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout5 = uigridlayout(app.Panel);
            app.NodesDropDownLabel = uilabel(app.GridLayout5);
            app.NodesDropDownLabel.Text = 'Nodes';
            app.NodesDropDown = uidropdown(app.GridLayout5);
            app.NodesDropDown.ValueChangedFcn = createCallbackFcn(app, @NodesDropDownValueChanged, true);
            app.LinksDropDownLabel = uilabel(app.GridLayout5);
            app.LinksDropDownLabel.Text = 'Links';
            app.LinksDropDown = uidropdown(app.GridLayout5);
            app.LinksDropDown.ValueChangedFcn = createCallbackFcn(app, @LinksDropDownValueChanged, true);
            app.NodeDataPanel = uipanel(app.GridLayout5);
            app.NodeDataPanel.Title = 'Node Data';
            app.GridLayout2 = uigridlayout(app.NodeDataPanel);
            app.YLabel = uilabel(app.GridLayout2);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout2);
            app.ZLabel.Text = 'Z';
            app.NodeX = uilabel(app.GridLayout2);
            app.NodeY = uilabel(app.GridLayout2);
            app.NodeY.Text = 'Label2';
            app.NodeZ = uilabel(app.GridLayout2);
            app.NodeZ.Text = 'Label3';
            app.XLabel = uilabel(app.GridLayout2);
            app.XLabel.Text = 'X';
            app.LinkDataPanel = uipanel(app.GridLayout5);
            app.LinkDataPanel.Title = 'Link Data';
            app.GridLayout3 = uigridlayout(app.LinkDataPanel);
            app.TortuosityLabel = uilabel(app.GridLayout3);
            app.TortuosityLabel.Text = 'Tortuosity';
            app.LinksTortuosity = uilabel(app.GridLayout3);
            app.LinksTortuosity.Text = 'Tortuosity';
            app.LinksLength = uilabel(app.GridLayout3);
            app.LinksLength.Text = 'Length';
            app.LengthLabel = uilabel(app.GridLayout3);
            app.LengthLabel.Text = 'Length';
            app.DrawButton = uibutton(app.GridLayout5, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.ShowButton = uibutton(app.GridLayout5, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.BranchDiameterDataPanel = uipanel(app.GridLayout5);
            app.BranchDiameterDataPanel.Title = 'Branch Diameter Data';
            app.GridLayout4 = uigridlayout(app.BranchDiameterDataPanel);
            app.Branch1Label = uilabel(app.GridLayout4);
            app.Branch1Label.Text = 'Branch 1';
            app.Branch2Label = uilabel(app.GridLayout4);
            app.Branch2Label.Text = 'Branch 2';
            app.Branch3Label = uilabel(app.GridLayout4);
            app.Branch3Label.Text = 'Branch 3';
            app.Diameter1Label = uilabel(app.GridLayout4);
            app.Diameter1Label.Text = 'Diameter 1';
            app.Diameter2Label = uilabel(app.GridLayout4);
            app.Diameter2Label.Text = 'Diameter 2';
            app.Diameter3Label = uilabel(app.GridLayout4);
            app.Diameter3Label.Text = 'Diameter 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExampleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end