classdef SettingPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        GetIMG                          matlab.ui.container.toolbar.PushTool
        link_search_condition_help_button  matlab.ui.control.Button
        num_pixel_grad_vec_help_button  matlab.ui.control.Button
        bifur_link_condition_help_button  matlab.ui.control.Button
        pruning_length_ratio_help_button  matlab.ui.control.Button
        xy_z_ratio_help_button          matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        link_search_conditionEditField  matlab.ui.control.NumericEditField
        link_search_conditionEditFieldLabel  matlab.ui.control.Label
        num_pixel_grad_vecEditField     matlab.ui.control.NumericEditField
        num_pixel_grad_vecEditFieldLabel  matlab.ui.control.Label
        bifur_link_conditionEditField   matlab.ui.control.NumericEditField
        bifur_link_conditionEditFieldLabel  matlab.ui.control.Label
        pruning_lengthEditField         matlab.ui.control.NumericEditField
        pruning_lengthEditFieldLabel    matlab.ui.control.Label
        xy_z_ratioEditField             matlab.ui.control.NumericEditField
        xy_z_ratioEditFieldLabel        matlab.ui.control.Label
        MainAxis                        matlab.ui.control.UIAxes
    end


    properties (Access = public) 
        CallingApp   % Main app object
        data = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            disp(1);
        end

        % Callback function
        function ButtonPushed(app, event)
            % Call main app's public function
            app.CallingApp.SettingButton.Enable = 'on';
            app.data = app.xy_z_ratioEditField.Value;
            disp(app.data);
            app.CallingApp.testdata = app.data;
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            try
                app.CallingApp.ImageButton.Enable = 'on';
            catch
                delete(app)
            end
            disp(2);
            % Delete the dialog box 
            delete(app)
            disp(3);
            
        end

        % Clicked callback: GetIMG
        function GetIMGClicked(app, event)
            path = uigetdir('C:\');
            disp(path);
            path = strcat(path,'\');
            app.CallingApp.stack = BinImageBuild(path, app.CallingApp.xy_z_ratio);
            p = msgbox('Processing...');
            disp('IMG LOADED')
            
            cla (app.MainAxis,'reset');
            hold (app.MainAxis,'on');
            disp('initiated');
            app.CallingApp.isosurf = isosurface(app.CallingApp.stack,0);
            disp('IsoSurfEnd');
            app.CallingApp.isocaps = isocaps(app.CallingApp.stack,0);
            disp('IsoCapsEnd');
            
            disp('11');
            col=[.9 .9 .9];
            hiso = patch(app.MainAxis,app.CallingApp.isosurf,'FaceColor',col,'EdgeColor','none');
            patch(app.MainAxis,app.CallingApp.isocaps,'FaceColor',col,'EdgeColor','none');
            %axis(app.MainAxis,'equal'); 
            %axis(app.MainAxis,'off'); 
            lighting(app.MainAxis,'phong');
            isonormals(app.CallingApp.stack,hiso);
            alpha(app.MainAxis,0.5);
            set(app.MainAxis,'DataAspectRatio',[1 1 1]);
            camlight(app.MainAxis,'right');
            hold(app.MainAxis,'on');        
            set(app.MainAxis,'Color','white');
            
            view(app.MainAxis,[1229 512 46]);
            delete(p)
            disp('End')
            msgbox('Complete!');
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            %main app 으로 값 넘겨주기
            app.CallingApp.xy_z_ratio=app.xy_z_ratioEditField.Value;
            app.CallingApp.pruning_length=app.pruning_lengthEditField.Value;
            app.CallingApp.bifur_link_condition=app.bifur_link_conditionEditField.Value;
            app.CallingApp.num_pixel_grad_vec=app.num_pixel_grad_vecEditField.Value;
            app.CallingApp.link_search_condition=app.link_search_conditionEditField.Value;
            disp(app.CallingApp.xy_z_ratio);
        end

        % Button pushed function: xy_z_ratio_help_button
        function xy_z_ratio_help_buttonButtonPushed(app, event)
            msgbox({'Length ratio between xy pixel and z pixel';'To form realistic structure, image stack will be elongated to match the';'x-y-z length ratio as 1:1:1.';'input =(z pixel distance) / (xy pixel distance)';'WARNING: The input must be in integer form!! '},'Help');
        end

        % Button pushed function: pruning_length_ratio_help_button
        function pruning_length_ratio_help_buttonButtonPushed(app, event)
            msgbox({'Prune the branches of raw skeleton ';'branches of skeletons which contain less than x number of pixels will be pruned'},'Help');
        end

        % Button pushed function: bifur_link_condition_help_button
        function bifur_link_condition_help_buttonButtonPushed(app, event)
            msgbox({'Condition for searching bifurcation node';'Only nodes with all links longer than x pixels will be chosen as';'bifurcation node'},'Help');
        end

        % Button pushed function: num_pixel_grad_vec_help_button
        function num_pixel_grad_vec_help_buttonButtonPushed(app, event)
            msgbox({'Number of pixels for calculating gradient vector';'Gradient vector of each bifurcated link will be calculated based on inputted number';'The number counting starts from bifurcation node'},'Help')
        end

        % Button pushed function: link_search_condition_help_button
        function link_search_condition_help_buttonButtonPushed(app, event)
            msgbox({'Condition for searching investigated link';'Only links longer than x pixels will be chosen and investigated '},'Help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.GetIMG = uipushtool(app.Toolbar);
            app.GetIMG.ClickedCallback = createCallbackFcn(app, @GetIMGClicked, true);
            app.MainAxis = uiaxes(app.UIFigure);
            title(app.MainAxis, 'Title')
            xlabel(app.MainAxis, 'X')
            ylabel(app.MainAxis, 'Y')
            zlabel(app.MainAxis, 'Z')
            app.xy_z_ratioEditFieldLabel = uilabel(app.UIFigure);
            app.xy_z_ratioEditFieldLabel.Text = 'xy_z_ratio';
            app.xy_z_ratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.xy_z_ratioEditField.Value = 4;
            app.pruning_lengthEditFieldLabel = uilabel(app.UIFigure);
            app.pruning_lengthEditFieldLabel.Text = 'pruning_length';
            app.pruning_lengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.pruning_lengthEditField.Value = 50;
            app.bifur_link_conditionEditFieldLabel = uilabel(app.UIFigure);
            app.bifur_link_conditionEditFieldLabel.Text = 'bifur_link_condition';
            app.bifur_link_conditionEditField = uieditfield(app.UIFigure, 'numeric');
            app.bifur_link_conditionEditField.Value = 20;
            app.num_pixel_grad_vecEditFieldLabel = uilabel(app.UIFigure);
            app.num_pixel_grad_vecEditFieldLabel.Text = 'num_pixel_grad_vec';
            app.num_pixel_grad_vecEditField = uieditfield(app.UIFigure, 'numeric');
            app.num_pixel_grad_vecEditField.Value = 5;
            app.link_search_conditionEditFieldLabel = uilabel(app.UIFigure);
            app.link_search_conditionEditFieldLabel.Text = 'link_search_condition';
            app.link_search_conditionEditField = uieditfield(app.UIFigure, 'numeric');
            app.link_search_conditionEditField.Value = 20;
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.xy_z_ratio_help_button = uibutton(app.UIFigure, 'push');
            app.xy_z_ratio_help_button.ButtonPushedFcn = createCallbackFcn(app, @xy_z_ratio_help_buttonButtonPushed, true);
            app.xy_z_ratio_help_button.Text = '?';
            app.pruning_length_ratio_help_button = uibutton(app.UIFigure, 'push');
            app.pruning_length_ratio_help_button.ButtonPushedFcn = createCallbackFcn(app, @pruning_length_ratio_help_buttonButtonPushed, true);
            app.pruning_length_ratio_help_button.Text = '?';
            app.bifur_link_condition_help_button = uibutton(app.UIFigure, 'push');
            app.bifur_link_condition_help_button.ButtonPushedFcn = createCallbackFcn(app, @bifur_link_condition_help_buttonButtonPushed, true);
            app.bifur_link_condition_help_button.Text = '?';
            app.num_pixel_grad_vec_help_button = uibutton(app.UIFigure, 'push');
            app.num_pixel_grad_vec_help_button.ButtonPushedFcn = createCallbackFcn(app, @num_pixel_grad_vec_help_buttonButtonPushed, true);
            app.num_pixel_grad_vec_help_button.Text = '?';
            app.link_search_condition_help_button = uibutton(app.UIFigure, 'push');
            app.link_search_condition_help_button.ButtonPushedFcn = createCallbackFcn(app, @link_search_condition_help_buttonButtonPushed, true);
            app.link_search_condition_help_button.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SettingPanel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end